classdef test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        initButton            matlab.ui.control.Button
        calc_totalButton      matlab.ui.control.Button
        disp_totalButton      matlab.ui.control.Button
        workspaceButton       matlab.ui.control.Button
        TabGroup2             matlab.ui.container.TabGroup
        settingsTab           matlab.ui.container.Tab
        TabGroup              matlab.ui.container.TabGroup
        SensorsTab            matlab.ui.container.Tab
        UITableSensors        matlab.ui.control.Table
        MCUTab                matlab.ui.container.Tab
        TransmissionTab       matlab.ui.container.Tab
        modulesimulationsTab  matlab.ui.container.Tab
        calc_sensorButton     matlab.ui.control.Button
        disp_sensorButton     matlab.ui.control.Button
        maximumnumberofmeasurementsperdayEditFieldLabel  matlab.ui.control.Label
        nmax                  matlab.ui.control.NumericEditField
        uitleg                matlab.ui.control.Label
        TotalSystemTab        matlab.ui.container.Tab
    end

    
    properties (Access = public)
        init %initmeuk
        Results %results struct of all results of calc_ functions
        list %geeft lbox
        SelectedSensors
        SelectedMCU
        SelectedTransmission
        SelectedNames
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [T_alive, E_available, Sensor, Sensorname, MCU, MCUname, Transmission, Transmissionname] = init_main();
            app.init.T_alive = T_alive;
            app.init.E_available = E_available;
            app.init.Sensor = Sensor;
            app.init.Sensorname = Sensorname;
            app.init.MCU = MCU;
            app.init.MCUname = MCUname;
            app.init.Transmission = Transmission;
            app.init.Transmissionname = Transmissionname;
            
            
            checked = false(size(Sensorname,2),1);
            interval = Sensor(3,:).'/24*3600; %converts default measurement into interval.
            parameters = Sensor(4:end,:).';
            sensorname2 = Sensorname.';
            cellarray = [ checked, sensorname2, interval, parameters];
            app.UITableSensors.ColumnEditable(1) = true;
            app.UITableSensors.ColumnEditable(4) = true;%zorgt er voor dat alleen interval colom aangepast kan worden
            app.UITableSensors.ColumnFormat = {'logical',[]};
            app.UITableSensors.Data = [cellarray];
        end

        % Button pushed function: initButton
        function initButtonPushed(app, event)
            [T_alive, E_available, Sensor, Sensorname, MCU, MCUname, Transmission, Transmissionname] = init_main();
            app.init.T_alive = T_alive;
            app.init.E_available = E_available;
            app.init.Sensor = Sensor;
            app.init.Sensorname = Sensorname;
            app.init.MCU = MCU;
            app.init.MCUname = MCUname;
            app.init.Transmission = Transmission;
            app.init.Transmissionname = Transmissionname;
            
            
            checked = false(size(Sensorname,2),1);
            interval = Sensor(3,:).'/24*3600; %converts default measurement into interval.
            parameters = Sensor(4:end,:).';
            sensorname2 = Sensorname.';
            cellarray = [ checked, sensorname2, interval, parameters];
            app.UITableSensors.Data = [cellarray];
            
            
        end

        % Button pushed function: calc_sensorButton
        function calc_sensorButtonPushed(app, event)
            N_Max = app.nmax.Value;
            [app.Results.Sensor, ~] = calc_sensors(app.SelectedSensors , N_Max);
 
        end

        % Button pushed function: calc_totalButton
        function calc_totalButtonPushed(app, event)
            S_Sensors = app.SelectedSensors;
            S_MCU = MCU(:,1);
            S_Com = Transmission(:,1);
            S_names = [app.init.Sensorname(:, app.SelectedSensors) MCUname(1,1) Transmissionname(1,1) "Total"];
            I_Array = [ (3600) 1 0 1 0; (3*3600) 1 1 1 1];
            T_Max = 3*24*3600;
            E_Max = 30000;
            
            [P_Sub, E_Sub, measurements2, time,tmeasurement, tltest] = calc_totalenergy(S_Sensors, S_MCU, S_Com, I_Array, T_Max, E_Max);
            app.Results.total = {P_Sub, E_Sub, measurements2, time, tmeasurement, tltest}; 
        end

        % Button pushed function: disp_totalButton
        function disp_totalButtonPushed(app, event)
            disp_totalenergy(P_Sub, E_Sub, time, tmeasurement, S_names);
        end

        % Button pushed function: disp_sensorButton
        function disp_sensorButtonPushed(app, event)
            disp_sensors(app.Results.Sensor, app.init.Sensorname(:, app.SelectedSensors));
        end

        % Button pushed function: workspaceButton
        function workspaceButtonPushed(app, event)
            ws_SelectedSensors = app.SelectedSensors;


            test = 0;
        end

        % Cell edit callback: UITableSensors
        function UITableSensorsCellEdit(app, event)
            indices = event.Indices;
            newData = str2double(event.NewData);
            %alle aanpassingen in de tabel worden opgeslagen in
            %app.UITableSensors.Data, als check box aangepast word dan ook
            %aangepast in SelectedItems.
            app.SelectedSensors = app.init.Sensor(:,app.UITableSensors.Data(:,1)=='1');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.initButton = uibutton(app.UIFigure, 'push');
            app.initButton.ButtonPushedFcn = createCallbackFcn(app, @initButtonPushed, true);
            app.initButton.Text = 'init';
            app.calc_totalButton = uibutton(app.UIFigure, 'push');
            app.calc_totalButton.ButtonPushedFcn = createCallbackFcn(app, @calc_totalButtonPushed, true);
            app.calc_totalButton.Text = 'calc_total';
            app.disp_totalButton = uibutton(app.UIFigure, 'push');
            app.disp_totalButton.ButtonPushedFcn = createCallbackFcn(app, @disp_totalButtonPushed, true);
            app.disp_totalButton.Text = 'disp_total';
            app.workspaceButton = uibutton(app.UIFigure, 'push');
            app.workspaceButton.ButtonPushedFcn = createCallbackFcn(app, @workspaceButtonPushed, true);
            app.workspaceButton.Text = 'workspace';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.settingsTab = uitab(app.TabGroup2);
            app.settingsTab.Title = 'settings';
            app.TabGroup = uitabgroup(app.settingsTab);
            app.SensorsTab = uitab(app.TabGroup);
            app.SensorsTab.Title = 'Sensors';
            app.UITableSensors = uitable(app.SensorsTab);
            app.UITableSensors.ColumnName = {'Name'; 'Type'; 'Interval[s]'; 'activated'; 'V [V]'; 'I_m[ma]'; 'T_m[s]'; 'T_p[s]'; 'I_ds[mA]'};
            app.UITableSensors.RowName = {};
            app.UITableSensors.CellEditCallback = createCallbackFcn(app, @UITableSensorsCellEdit, true);
            app.MCUTab = uitab(app.TabGroup);
            app.MCUTab.Title = 'MCU';
            app.TransmissionTab = uitab(app.TabGroup);
            app.TransmissionTab.Title = 'Transmission';
            app.modulesimulationsTab = uitab(app.TabGroup2);
            app.modulesimulationsTab.Title = 'module simulations';
            app.calc_sensorButton = uibutton(app.modulesimulationsTab, 'push');
            app.calc_sensorButton.ButtonPushedFcn = createCallbackFcn(app, @calc_sensorButtonPushed, true);
            app.calc_sensorButton.Text = 'calc_sensor';
            app.disp_sensorButton = uibutton(app.modulesimulationsTab, 'push');
            app.disp_sensorButton.ButtonPushedFcn = createCallbackFcn(app, @disp_sensorButtonPushed, true);
            app.disp_sensorButton.Text = 'disp_sensor';
            app.maximumnumberofmeasurementsperdayEditFieldLabel = uilabel(app.modulesimulationsTab);
            app.maximumnumberofmeasurementsperdayEditFieldLabel.Text = {'maximum number'; 'of measurements '; 'per day'};
            app.nmax = uieditfield(app.modulesimulationsTab, 'numeric');
            app.uitleg = uilabel(app.modulesimulationsTab);
            app.uitleg.Text = '(selected sensors in settings tab will be used)';
            app.TotalSystemTab = uitab(app.TabGroup2);
            app.TotalSystemTab.Title = 'Total System';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end